classdef lab5GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Lab5CarMQTTGUIUIFigure  matlab.ui.Figure
        Panel                   matlab.ui.container.Panel
        BeepButton              matlab.ui.control.Button
        ServoHeadPanel          matlab.ui.container.Panel
        TiltSlider              matlab.ui.control.Slider
        TiltSliderLabel         matlab.ui.control.Label
        PanSlider               matlab.ui.control.Slider
        PanSliderLabel          matlab.ui.control.Label
        SpeedSlider             matlab.ui.control.Slider
        SpeedSliderLabel        matlab.ui.control.Label
        ClickintheareabelowthenusekeyboardkeystodriveLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        mqClient
        priorKey = ''
    end

    methods (Access = private)

        function sendMessage(app, messageType, payload)
            messageStruct.type = messageType;
            if nargin > 2
                messageStruct.payload = payload;
            end
            message = jsonencode(messageStruct);
            fprintf("Sending: %s\n", message);
            app.mqClient.write("me435/fisherds/to_pi", message);
        end


        function myCallback(app, topic, message)
            fprintf("Received: %s\n", message);
            messageStruct = jsondecode(message);
            messageType = messageStruct.type
            %             if messageType == "something_with_a_payload"
            %                     payload = messageStruct.payload;
            %             end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
%             app.mqClient = mqttclient("tcp://test.mosquitto.org");
            app.mqClient = mqttclient("tcp://broker.hivemq.com");
            subscribe(app.mqClient, "me435/fisherds/#", "Callback", @app.myCallback)
        end

        % Key press function: Lab5CarMQTTGUIUIFigure
        function Lab5CarMQTTGUIUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key, app.priorKey)
                return;
            end
            app.priorKey = key;
            speed = round(app.SpeedSlider.Value);

            if strcmp(key, "uparrow")
                app.sendMessage("set_speeds", [speed speed speed speed])
            end
            if strcmp(key, "downarrow")
                app.sendMessage("set_speeds", [-speed -speed -speed -speed])
            end
            if strcmp(key, "leftarrow")
                app.sendMessage("set_speeds", [-speed speed speed -speed])
            end
            if strcmp(key, "rightarrow")

                app.sendMessage("set_speeds", [speed -speed -speed speed])
            end
            if strcmp(key, "a")
                app.sendMessage("set_speeds", [-speed/2 -speed/2 speed/2 speed/2])
            end
            if strcmp(key, "d")
                app.sendMessage("set_speeds", [speed/2 speed/2 -speed/2 -speed/2])
            end

        end

        % Key release function: Lab5CarMQTTGUIUIFigure
        function Lab5CarMQTTGUIUIFigureKeyRelease(app, event)
            fprintf("Send Stop\n");
            app.priorKey = "";
            app.sendMessage("stop")

        end

        % Value changing function: PanSlider
        function PanSliderValueChanging(app, event)
            panValue = round(event.Value);
            app.sendMessage("pan", panValue);
        end

        % Value changing function: TiltSlider
        function TiltSliderValueChanging(app, event)
            tiltValue = round(event.Value);
            app.sendMessage("tilt", tiltValue);
        end

        % Button pushed function: BeepButton
        function BeepButtonPushed(app, event)
            app.sendMessage("beep")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Lab5CarMQTTGUIUIFigure = uifigure('Visible', 'off');
            app.Lab5CarMQTTGUIUIFigure.Name = 'Lab 5 Car MQTT GUI';
            app.Lab5CarMQTTGUIUIFigure.KeyPressFcn = createCallbackFcn(app, @Lab5CarMQTTGUIUIFigureKeyPress, true);
            app.Lab5CarMQTTGUIUIFigure.KeyReleaseFcn = createCallbackFcn(app, @Lab5CarMQTTGUIUIFigureKeyRelease, true);
            app.ClickintheareabelowthenusekeyboardkeystodriveLabel = uilabel(app.Lab5CarMQTTGUIUIFigure);
            app.ClickintheareabelowthenusekeyboardkeystodriveLabel.Text = 'Click in the area below, then use keyboard keys to drive';
            app.SpeedSliderLabel = uilabel(app.Lab5CarMQTTGUIUIFigure);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.Lab5CarMQTTGUIUIFigure);
            app.SpeedSlider.Value = 50;
            app.ServoHeadPanel = uipanel(app.Lab5CarMQTTGUIUIFigure);
            app.ServoHeadPanel.Title = 'Servo Head';
            app.PanSliderLabel = uilabel(app.ServoHeadPanel);
            app.PanSliderLabel.Text = 'Pan';
            app.PanSlider = uislider(app.ServoHeadPanel);
            app.PanSlider.Limits = [20 160];
            app.PanSlider.ValueChangingFcn = createCallbackFcn(app, @PanSliderValueChanging, true);
            app.PanSlider.Value = 90;
            app.TiltSliderLabel = uilabel(app.ServoHeadPanel);
            app.TiltSliderLabel.Text = 'Tilt';
            app.TiltSlider = uislider(app.ServoHeadPanel);
            app.TiltSlider.Limits = [70 160];
            app.TiltSlider.ValueChangingFcn = createCallbackFcn(app, @TiltSliderValueChanging, true);
            app.TiltSlider.Value = 90;
            app.Panel = uipanel(app.Lab5CarMQTTGUIUIFigure);
            app.Panel.Title = 'Panel';
            app.BeepButton = uibutton(app.Panel, 'push');
            app.BeepButton.ButtonPushedFcn = createCallbackFcn(app, @BeepButtonPushed, true);
            app.BeepButton.Text = 'Beep';
            % Show the figure after all components are created
            app.Lab5CarMQTTGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab5GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Lab5CarMQTTGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Lab5CarMQTTGUIUIFigure)
        end
    end
end